classdef v4DiffusionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectHUVECimagesButton       matlab.ui.control.Button
        SelectdyeimagesButton         matlab.ui.control.Button
        ImportDyeLamp                 matlab.ui.control.Lamp
        ImportimagesButton            matlab.ui.control.Button
        DetectedgesButton             matlab.ui.control.Button
        edgeDetectLamp                matlab.ui.control.Lamp
        ExtractdyeprofilesButton      matlab.ui.control.Button
        ExtractProfLamp               matlab.ui.control.Lamp
        ComputepermeabilityButton     matlab.ui.control.Button
        PermeabilityLamp              matlab.ui.control.Lamp
        SelectoutputdirectoryButton   matlab.ui.control.Button
        ExportplotsanddataButton      matlab.ui.control.Button
        ExportLamp                    matlab.ui.control.Lamp
        ExperimentalParametersLabel   matlab.ui.control.Label
        TimebetweenframessEditFieldLabel  matlab.ui.control.Label
        tStep                         matlab.ui.control.NumericEditField
        ImagescaleumpxEditFieldLabel  matlab.ui.control.Label
        umpx                          matlab.ui.control.NumericEditField
        UltrasoundonframeEditFieldLabel  matlab.ui.control.Label
        USOn                          matlab.ui.control.NumericEditField
        UltrasoundoffframeEditFieldLabel  matlab.ui.control.Label
        USOff                         matlab.ui.control.NumericEditField
        MillerLabIanKinstlingerrev0420Label  matlab.ui.control.Label
        ButtonGroup                   matlab.ui.container.ButtonGroup
        HUVECedgeButton               matlab.ui.control.RadioButton
        DiamovertimeButton            matlab.ui.control.RadioButton
        DyeprofileButton              matlab.ui.control.RadioButton
        DyeedgeButton                 matlab.ui.control.RadioButton
        DyeAccum                      matlab.ui.control.RadioButton
        ImageNumberSliderLabel        matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        UITable                       matlab.ui.control.Table
        PermeabilitydataLabel         matlab.ui.control.Label
        I_0EditFieldLabel             matlab.ui.control.Label
        I_0EditField                  matlab.ui.control.NumericEditField
        ROIRed                        matlab.ui.control.Label
        ImportProgress                matlab.ui.control.Label
        EndoLamp                      matlab.ui.control.Lamp
        CalulateI_0usingframesLabel   matlab.ui.control.Label
        NumFramesLower                matlab.ui.control.Spinner
        Label                         matlab.ui.control.Label
        NumFramesUpper                matlab.ui.control.Spinner
        outdirLamp                    matlab.ui.control.Lamp
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        endoDir % Description
        dyeDir
        x
        y
        z
        endoRaw
        dyeRaw
        logo
        endoEdges
        dyeEdges
        endoShow
        diamMean
        ROI
        diam
        diamSD
        topMean
        topSD
        bottomMean
        bottomSD
        topBotMean
        topBotSD
        I0
        verts
        outDir
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.logo = imread('Logo.jpg');
            % Fill figure with axes and remove tick labels
            %app.UIAxes.Position = [0 0 app.UIAxes.Position(3:4)];
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow(app.logo, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
            
            app.UITable.Visible = 'off';
            app.PermeabilitydataLabel.Visible = 'off';
            app.I_0EditField.Visible = 'off';
            app.I_0EditFieldLabel.Visible = 'off';
            app.ROIRed.Visible = 'off';
            app.ImportProgress.Visible = 'off';
            app.NumFramesLower.Visible = 'off';
            app.NumFramesUpper.Visible = 'off';
            app.CalulateI_0usingframesLabel.Visible = 'off';
            app.Label.Visible = 'off';
            app.ButtonGroup.Visible = 'off';
            app.Slider.Visible = 'off';
            app.ImageNumberSliderLabel.Visible = 'off';
        end

        % Button pushed function: ImportimagesButton
        function ImportimagesButtonPushed(app, event)
            app.ImportProgress.Visible = 'on';
            app.ImportProgress.Text = '0 %';
            drawnow;
            endoIms = dir([app.endoDir.name]);
            dyeIms = dir([app.dyeDir.name]);
            
            for i = 3:length(endoIms)
                
                app.endoRaw(:,:,i-2) = im2double(imread([endoIms(i).name]));
                app.dyeRaw(:,:,i-2) = im2double(imread([dyeIms(i).name]));
                app.ImportProgress.Text = strcat(int2str(round(((i-2)/length(endoIms))*90)), ' %');
                drawnow
                
            end
            
            app.endoRaw = subvolume(app.endoRaw, [512,1536,NaN,NaN,NaN,NaN]); %remove vignetting
            app.dyeRaw = subvolume(app.dyeRaw, [512,1536,NaN,NaN,NaN,NaN]); %remove vignetting
            
            [app.x,app.y,app.z] = size(app.endoRaw);
            app.ImportProgress.Text = '100 %';
            app.ImportProgress.FontColor = 'green';
            app.ImportProgress.BackgroundColor = 'black';
            
        end

        % Button pushed function: SelectdyeimagesButton
        function SelectdyeimagesButtonPushed(app, event)
            app.dyeDir = uipickfiles('REFilter','^','out','struct','Prompt','Select dye directory');
            
            app.ImportDyeLamp.Color = [0 1 0] ;
        end

        % Button pushed function: SelectHUVECimagesButton
        function SelectHUVECimagesButtonPushed(app, event)
            app.endoDir = uipickfiles('REFilter','^','out','struct','Prompt','Select HUVEC directory');
            app.EndoLamp.Color = [0 1 0] ;
        end

        % Button pushed function: DetectedgesButton
        function DetectedgesButtonPushed(app, event)
            % Edge detect endo images
            
            app.edgeDetectLamp.Color = [1 1 0];
            drawnow;
            
            %app.z = 5;
            for j = 1:app.z
                thisImg = app.endoRaw(:,:,j);
                gs = graythresh(thisImg);
                gsMult = 0.6;  %set empirically to optimize edge det
                for k = 1:app.y
                    currentX = 1;
                    val = 0;
                    while(val < gsMult*gs)
                        val = thisImg(currentX,k);
                        currentX = currentX + 1;
                    end
                    
                    top(j,k) = currentX;
                    
                    currentX = app.x;
                    val = 0;
                    while(val < gsMult*gs)
                        val = thisImg(currentX,k);
                        currentX = currentX -1;
                    end
                    
                    bottom(j,k) = currentX;
                end
                
            end
            
            app.diam = bottom-top;
            app.diamMean = mean(app.diam,2);
            app.diamSD = std(app.diam,0,2);
            app.topMean = mean(top,2);
            app.topSD = std(top,0,2);
            app.bottomMean = mean(bottom,2);
            app.bottomSD = std(bottom,0,2);
            
            
            % Visualize edge detection to empirically assess quality
            app.endoEdges = 5*app.endoRaw;
            
            for i = 1:app.z
                app.endoEdges(1+round(app.topMean(i)),:,i) = 1;
                app.endoEdges(2+round(app.topMean(i)),:,i) = 1;
                app.endoEdges(3+round(app.topMean(i)),:,i) = 1;
                app.endoEdges(-1+round(app.topMean(i)),:,i) = 1;
                app.endoEdges(-2+round(app.topMean(i)),:,i) = 1;
                app.endoEdges(-3+round(app.topMean(i)),:,i) = 1;
                app.endoEdges(round(app.topMean(i)),:,i) = 1;
                app.endoEdges(1+round(app.bottomMean(i)),:,i) = 1;
                app.endoEdges(2+round(app.bottomMean(i)),:,i) = 1;
                app.endoEdges(3+round(app.bottomMean(i)),:,i) = 1;
                app.endoEdges(-1+round(app.bottomMean(i)),:,i) = 1;
                app.endoEdges(-2+round(app.bottomMean(i)),:,i) = 1;
                app.endoEdges(-3+round(app.bottomMean(i)),:,i) = 1;
                app.endoEdges(round(app.bottomMean(i)),:,i) = 1;
                
            end
            
            
            app.dyeEdges = app.dyeRaw;
            
            for i = 1:app.z
                
                app.dyeEdges(1+round(app.topMean(i)),:,i) = 1;
                app.dyeEdges(2+round(app.topMean(i)),:,i) = 1;
                app.dyeEdges(3+round(app.topMean(i)),:,i) = 1;
                app.dyeEdges(-1+round(app.topMean(i)),:,i) = 1;
                app.dyeEdges(-2+round(app.topMean(i)),:,i) = 1;
                app.dyeEdges(-3+round(app.topMean(i)),:,i) = 1;
                app.dyeEdges(round(app.topMean(i)),:,i) = 1;
                app.dyeEdges(1+round(app.bottomMean(i)),:,i) = 1;
                app.dyeEdges(2+round(app.bottomMean(i)),:,i) = 1;
                app.dyeEdges(3+round(app.bottomMean(i)),:,i) = 1;
                app.dyeEdges(-1+round(app.bottomMean(i)),:,i) = 1;
                app.dyeEdges(-2+round(app.bottomMean(i)),:,i) = 1;
                app.dyeEdges(-3+round(app.bottomMean(i)),:,i) = 1;
                app.dyeEdges(round(app.bottomMean(i)),:,i) = 1;
                
            end
            
            for m = 1:app.z
                
                app.endoShow(:,:,m) = imresize(app.endoEdges(:,:,m), 0.25);
                
            end
            
            app.edgeDetectLamp.Color = [0 1 0];
            
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow(app.endoShow(:,:,1), 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
            app.Slider.Limits = [1 app.z];
            app.Slider.Value = 1;
            app.Slider.Visible = 'on';
            app.ButtonGroup.Visible = 'on';

            
            app.ButtonGroup.SelectedObject = app.HUVECedgeButton;
            
        end

        % Callback function
        function SliderValueChanging(app, event)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if selectedButton == app.HUVECedgeButton
                
                title(app.UIAxes, []);
                xlabel(app.UIAxes, []);
                ylabel(app.UIAxes, []);
                app.UIAxes.XAxis.TickLabels = {};
                app.UIAxes.YAxis.TickLabels = {};
                % Display image and stretch to fill axes
                I = imshow(app.endoEdges(:,:,1), 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                
                app.Slider.Limits = [1 app.z];
                app.Slider.Value = 1;
                app.Slider.Visible = 'on';
                app.ImageNumberSliderLabel.Visible = 'on';
                
            elseif selectedButton == app.DiamovertimeButton
                
                reset(app.UIAxes)
                xlabel(app.UIAxes, 'Time (sec)');
                ylabel(app.UIAxes, 'Diameter (\mum)');
                plot(app.UIAxes, (app.tStep.Value*[1:app.z])-30, app.diamMean*app.umpx.Value, 'LineWidth', 2);
                set(app.UIAxes, 'FontSize', 16)
                app.Slider.Visible = 'off';
                app.ImageNumberSliderLabel.Visible = 'off';
                app.UIAxes.YLim = [150 Inf];
                
            elseif selectedButton == app.DyeAccum
                
                reset(app.UIAxes)
                xlabel(app.UIAxes, 'Time (sec)');
                ylabel(app.UIAxes, 'Fluorescence intensity (%)');
                plot(app.UIAxes, (app.tStep.Value*[1:app.z])-30, 100*app.topBotMean, 'LineWidth', 2);
                set(app.UIAxes, 'FontSize', 16)
                app.Slider.Visible = 'off';
                app.ImageNumberSliderLabel.Visible = 'off';
                
            elseif selectedButton == app.DyeprofileButton
                
                reset(app.UIAxes)
                xlabel(app.UIAxes, 'Distance from channel edge (px)');
                ylabel(app.UIAxes, 'Fluorescence intensity (%)');
                set(app.UIAxes, 'FontSize', 16)
                plot(app.UIAxes, [100*app.ROI(1).topProfileMean], 'Color', 'blue', 'LineWidth', 2);
                app.UIAxes.YLim = [0 40];
                app.UIAxes.XLim = [0 1000];
                app.Slider.Visible = 'on';
                app.ImageNumberSliderLabel.Visible = 'on';
                app.Slider.Value = 1;
                
            elseif selectedButton == app.DyeedgeButton
                
                reset(app.UIAxes)
            
                title(app.UIAxes, []);
                xlabel(app.UIAxes, []);
                ylabel(app.UIAxes, []);
                app.UIAxes.XAxis.TickLabels = {};
                app.UIAxes.YAxis.TickLabels = {};
                % Display image and stretch to fill axes
                I = imshow(app.dyeEdges(:,:,1), 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                
                app.Slider.Limits = [1 app.z];
                app.Slider.Value = 1;
                app.Slider.Visible = 'on';
                app.ImageNumberSliderLabel.Visible = 'on';
                
            end
            
        end

        % Button pushed function: ExtractdyeprofilesButton
        function ExtractdyeprofilesButtonPushed(app, event)
            
            app.ExtractProfLamp.Color = [1 1 0];
            drawnow;
            
            for p = 1:app.z
                
                temp = app.dyeRaw(:,:,p);
                center(p) = (app.topMean(p) + app.bottomMean(p))/2;
                app.ROI(p).top = flip(temp(1:round(app.topMean(p)),:));
                app.ROI(p).bot = temp(round(app.bottomMean(p)):app.x,:);
                app.ROI(p).centerline = temp(round(center(p)),:);
                
            end
            
            for q = 1:app.z
                
                app.ROI(q).topMean = mean2([app.ROI(q).top]);
                app.ROI(q).botMean = mean2([app.ROI(q).bot]);
                app.ROI(q).topSD = std2([app.ROI(q).top]);
                app.ROI(q).botSD = std2([app.ROI(q).bot]);
                app.ROI(q).topProfileMean = mean([app.ROI(q).top],2);
                app.ROI(q).botProfileMean = mean([app.ROI(q).bot],2);
                app.ROI(q).topProfileSD = std([app.ROI(q).top],0,2);
                app.ROI(q).botProfileSD = std([app.ROI(q).bot],0,2);
                app.ROI(q).centMean = mean([app.ROI(q).centerline]);
                app.ROI(q).centSD = std([app.ROI(q).centerline]);
                
            end
            
            app.topBotMean = ([app.ROI.topMean] + [app.ROI.botMean])./2;
            app.topBotSD = ([app.ROI.topSD] + [app.ROI.botSD])./2;
            
            app.ExtractProfLamp.Color = [0 1 0];
            
        end

        % Value changing function: Slider
        function SliderValueChanging2(app, event)
            changingValue = event.Value;
            
            if app.ButtonGroup.SelectedObject == app.HUVECedgeButton
                
                I = imshow(app.endoShow(:,:,round(changingValue)), 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                
            elseif app.ButtonGroup.SelectedObject == app.DyeprofileButton
                
                %reset(app.UIAxes);
                hold(app.UIAxes);
                
                %                 for k = 1:round(changingValue)-1
                %
                %                     if k >= app.USOn && k <= app.USOff
                %                         plot(app.UIAxes, [app.ROI(k).topProfileMean], 'Color', [0.5 0 0], 'LineWidth', 1);
                %
                %                     else
                %                         plot(app.UIAxes, [app.ROI(k).topProfileMean], 'Color', [0.5 0.5 0.5], 'LineWidth', 1);
                %                     end
                %
                %                 end
                
                plot(app.UIAxes, [100*app.ROI(round(changingValue)).topProfileMean], 'Color', [0 0 1], 'LineWidth', 2);
                app.UIAxes.XLim = [0 1000];
                
            elseif app.ButtonGroup.SelectedObject == app.DyeedgeButton
                
                I = imshow(app.dyeEdges(:,:,round(changingValue)), 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                
                
            end
        end

        % Button pushed function: ComputepermeabilityButton
        function ComputepermeabilityButtonPushed(app, event)
            app.PermeabilityLamp.Color = [1 1 0];
            
            app.NumFramesLower.Visible = 'on';
            app.NumFramesUpper.Visible = 'on';
            app.CalulateI_0usingframesLabel.Visible = 'on';
            app.Label.Visible = 'on';
            
            app.UITable.Visible = 'on';
            app.PermeabilitydataLabel.Visible = 'on';
            app.I_0EditField.Visible = 'on';
            app.I_0EditFieldLabel.Visible = 'on';
            
            app.I_0EditField.Value = mean([app.ROI(app.NumFramesLower.Value:app.NumFramesUpper.Value).centMean]);
            app.I0 = app.I_0EditField.Value;
            
            rad_cm = app.diamMean(2:end)./(2e4);
            
            
            %WholeGel method
            
            mean_dye_signal = app.topBotMean;
            USOn = app.USOn.Value;
            USOff = app.USOff.Value;
            umPerPx = app.umpx.Value;
            
            t_beforeUS = (app.tStep.Value.*(USOn-10:USOn-1))-30;
            I_beforeUS = mean_dye_signal(app.USOn.Value-10:app.USOn.Value-1);
            fit_beforeUS = coeffvalues(fit(t_beforeUS', I_beforeUS', 'poly1'));
            dIdt_before_US = fit_beforeUS(1);
            r_beforeUS = umPerPx*mean(app.diam(USOn-10:USOn-1))./2e4;
            whole_gel_p_beforeUS = 1e6*(dIdt_before_US.*r_beforeUS)./(2*app.I0);
            
            t_US = (app.tStep.Value.*(USOn:USOff-1))-30;
            I_US = mean_dye_signal(USOn:USOff-1);
            fit_US = coeffvalues(fit(t_US', I_US', 'poly1'));
            dIdt_US = fit_US(1);
            r_US = umPerPx*mean(app.diam(USOn:USOff-1))/2e4;
            whole_gel_p_US = 1e6*(dIdt_US.*r_US)./(2*app.I0);
            
            t_afterUS = (app.tStep.Value.*(USOff:USOff+9))-30;
            I_afterUS = mean_dye_signal(USOff:USOff+9);
            fit_afterUS = coeffvalues(fit(t_afterUS', I_afterUS', 'poly1'));
            dIdt_after_US = fit_afterUS(1);
            r_afterUS = umPerPx*mean(app.diam(USOff:USOff+9))/2e4;
            whole_gel_p_afterUS = 1e6*(dIdt_after_US.*r_afterUS)./(2*app.I0);
            
            app.UITable.Data{1,1} = whole_gel_p_beforeUS;
            app.UITable.Data{1,2} = whole_gel_p_US;
            app.UITable.Data{1,3} = whole_gel_p_afterUS;
            drawnow
            
            %User ROI
            
            clear ROI_eachSlice profiles
            
            app.ButtonGroup.Visible = 'off';
            app.Slider.Visible = 'off';
            app.ROIRed.Visible = 'on';
            
            reset(app.UIAxes');
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Display image
            I = imshow(5*app.endoRaw(:,:,1), 'Parent', app.UIAxes);
            % Set limits of axes
            %app.UIAxes.XLim = [0 I.XData(2)];
            %app.UIAxes.YLim = [0 I.YData(2)];
            
            %axes(app.UIFigure)
            %[app.x,app.y,BW,xi,yi] = roipoly;
            userROI = drawrectangle(app.UIAxes);
            wait(userROI);
            app.verts = userROI.Vertices;
            verts = app.verts;
            
            xmin = min(verts(:,1)); xmax = max(verts(:,1)); ymin = min(verts(:,1)); ymax = max(verts(:,2)); zmax = length(app.dyeRaw);
            
            ROI_eachSlice = subvolume(app.dyeRaw, [xmin xmax ymin ymax 1 zmax]);
            
            [xSlice, ySlice, zSlice] = size(ROI_eachSlice);
            
            for i = 1:zSlice
                
                mean_dye_signal(i) = mean2(ROI_eachSlice(:,:,i));
                temp = mean(ROI_eachSlice(:,:,i),2);
                profiles(:,i) = temp;
            end
            
            t_beforeUS = (app.tStep.Value.*(USOn-10:USOn-1))-30;
            I_beforeUS = mean_dye_signal(app.USOn.Value-10:app.USOn.Value-1);
            fit_beforeUS = coeffvalues(fit(t_beforeUS', I_beforeUS', 'poly1'));
            dIdt_before_US = fit_beforeUS(1);
            r_beforeUS = umPerPx*mean(app.diam(USOn-10:USOn-1))/2e4;
            userROI_p_beforeUS = 1e6*(dIdt_before_US.*r_beforeUS)./(2*app.I0);
            
            t_US = (app.tStep.Value.*(USOn:USOff-1))-30;
            I_US = mean_dye_signal(USOn:USOff-1);
            fit_US = coeffvalues(fit(t_US', I_US', 'poly1'));
            dIdt_US = fit_US(1);
            r_US = umPerPx*mean(app.diam(USOn:USOff-1))/2e4;
            userROI_p_US = 1e6*(dIdt_US.*r_US)./(2*app.I0);
            
            t_afterUS = (app.tStep.Value.*(USOff:USOff+9))-30;
            I_afterUS = mean_dye_signal(USOff:USOff+9);
            fit_afterUS = coeffvalues(fit(t_afterUS', I_afterUS', 'poly1'));
            dIdt_after_US = fit_afterUS(1);
            r_afterUS = umPerPx*mean(app.diam(USOff:USOff+9))/2e4;
            userROI_p_afterUS = 1e6*(dIdt_after_US.*r_afterUS)./(2*app.I0);
            
            app.UITable.Data{2,1} = userROI_p_beforeUS;
            app.UITable.Data{2,2} = userROI_p_US;
            app.UITable.Data{2,3} = userROI_p_afterUS;
            
            app.ButtonGroup.Visible = 'on';
            app.ROIRed.Visible = 'off';
            app.Slider.Visible = 'on';
            drawnow;
            
            
            % Sliding ROI
            
            clear mean_dye_signal thisSliceROI
            
            for i = 1:app.z
                
                thisSliceROI = app.dyeRaw(round(app.topMean(i))-300:round(app.topMean(i))-100,:,i);
                mean_dye_signal(i) = mean2(thisSliceROI);
                
            end
            
            t_beforeUS = (app.tStep.Value.*(USOn-10:USOn-1))-30;
            I_beforeUS = mean_dye_signal(app.USOn.Value-10:app.USOn.Value-1);
            fit_beforeUS = coeffvalues(fit(t_beforeUS', I_beforeUS', 'poly1'));
            dIdt_before_US = fit_beforeUS(1);
            r_beforeUS = umPerPx*mean(app.diam(USOn-10:USOn-1))/2e4;
            slideROI_p_beforeUS = 1e6*(dIdt_before_US.*r_beforeUS)./(2*app.I0);
            
            t_US = (app.tStep.Value.*(USOn:USOff-1))-30;
            I_US = mean_dye_signal(USOn:USOff-1);
            fit_US = coeffvalues(fit(t_US', I_US', 'poly1'));
            dIdt_US = fit_US(1);
            r_US = umPerPx*mean(app.diam(USOn:USOff-1))/2e4;
            slideROI_p_US = 1e6*(dIdt_US.*r_US)./(2*app.I0);
            
            t_afterUS = (app.tStep.Value.*(USOff:USOff+9))-30;
            I_afterUS = mean_dye_signal(USOff:USOff+9);
            fit_afterUS = coeffvalues(fit(t_afterUS', I_afterUS', 'poly1'));
            dIdt_after_US = fit_afterUS(1);
            r_afterUS = umPerPx*mean(app.diam(USOff:USOff+9))/2e4;
            slideROI_p_afterUS = 1e6*(dIdt_after_US.*r_afterUS)./(2*app.I0);
            
            app.UITable.Data{3,1} = slideROI_p_beforeUS;
            app.UITable.Data{3,2} = slideROI_p_US;
            app.UITable.Data{3,3} = slideROI_p_afterUS;
            
            
            app.PermeabilityLamp.Color = [0 1 0];
            
        end

        % Callback function
        function I0SliderValueChanging(app, event)
            changingValue = event.Value;
            
            app.I0 = mean([app.ROI(2:changingValue).centMean]);
            app.I_0EditField.Value = app.I0;
            
            rad_cm = app.diamMean(2:end)./(2e4);
            
            %WholeGel method
            
            mean_dye_signal = app.topBotMean;
            USOn = app.USOn.Value;
            USOff = app.USOff.Value;
            umPerPx = app.umpx.Value;
            
            t_beforeUS = (app.tStep.Value.*(USOn-10:USOn-1))-30;
            I_beforeUS = mean_dye_signal(app.USOn.Value-10:app.USOn.Value-1);
            fit_beforeUS = coeffvalues(fit(t_beforeUS', I_beforeUS', 'poly1'));
            dIdt_before_US = fit_beforeUS(1);
            r_beforeUS = umPerPx*mean(app.diam(USOn-10:USOn-1))./2e4;
            whole_gel_p_beforeUS = 1e6*(dIdt_before_US.*r_beforeUS)./(2*app.I0);
            
            t_US = (app.tStep.Value.*(USOn:USOff-1))-30;
            I_US = mean_dye_signal(USOn:USOff-1);
            fit_US = coeffvalues(fit(t_US', I_US', 'poly1'));
            dIdt_US = fit_US(1);
            r_US = umPerPx*mean(app.diam(USOn:USOff-1))/2e4;
            whole_gel_p_US = 1e6*(dIdt_US.*r_US)./(2*app.I0);
            
            t_afterUS = (app.tStep.Value.*(USOff:USOff+9))-30;
            I_afterUS = mean_dye_signal(USOff:USOff+9);
            fit_afterUS = coeffvalues(fit(t_afterUS', I_afterUS', 'poly1'));
            dIdt_after_US = fit_afterUS(1);
            r_afterUS = umPerPx*mean(app.diam(USOff:USOff+9))/2e4;
            whole_gel_p_afterUS = 1e6*(dIdt_after_US.*r_afterUS)./(2*app.I0);
            
            app.UITable.Data{1,1} = whole_gel_p_beforeUS;
            app.UITable.Data{1,2} = whole_gel_p_US;
            app.UITable.Data{1,3} = whole_gel_p_afterUS;
            drawnow
            
            %User ROI
            
            verts = app.verts;
            
            xmin = min(verts(:,1)); xmax = max(verts(:,1)); ymin = min(verts(:,1)); ymax = max(verts(:,2)); zmax = length(app.dyeRaw);
            
            ROI_eachSlice = subvolume(app.dyeRaw, [xmin xmax ymin ymax 1 zmax]);
            
            [xSlice, ySlice, zSlice] = size(ROI_eachSlice);
            
            for i = 1:zSlice
                
                mean_dye_signal(i) = mean2(ROI_eachSlice(:,:,i));
                temp = mean(ROI_eachSlice(:,:,i),2);
                profiles(:,i) = temp;
            end
            
            t_beforeUS = (app.tStep.Value.*(USOn-10:USOn-1))-30;
            I_beforeUS = mean_dye_signal(app.USOn.Value-10:app.USOn.Value-1);
            fit_beforeUS = coeffvalues(fit(t_beforeUS', I_beforeUS', 'poly1'));
            dIdt_before_US = fit_beforeUS(1);
            r_beforeUS = umPerPx*mean(app.diam(USOn-10:USOn-1))/2e4;
            userROI_p_beforeUS = 1e6*(dIdt_before_US.*r_beforeUS)./(2*app.I0);
            
            t_US = (app.tStep.Value.*(USOn:USOff-1))-30;
            I_US = mean_dye_signal(USOn:USOff-1);
            fit_US = coeffvalues(fit(t_US', I_US', 'poly1'));
            dIdt_US = fit_US(1);
            r_US = umPerPx*mean(app.diam(USOn:USOff-1))/2e4;
            userROI_p_US = 1e6*(dIdt_US.*r_US)./(2*app.I0);
            
            t_afterUS = (app.tStep.Value.*(USOff:USOff+9))-30;
            I_afterUS = mean_dye_signal(USOff:USOff+9);
            fit_afterUS = coeffvalues(fit(t_afterUS', I_afterUS', 'poly1'));
            dIdt_after_US = fit_afterUS(1);
            r_afterUS = umPerPx*mean(app.diam(USOff:USOff+9))/2e4;
            userROI_p_afterUS = 1e6*(dIdt_after_US.*r_afterUS)./(2*app.I0);
            
            app.UITable.Data{2,1} = userROI_p_beforeUS;
            app.UITable.Data{2,2} = userROI_p_US;
            app.UITable.Data{2,3} = userROI_p_afterUS;
            
            
            % Sliding ROI
            
            clear mean_dye_signal thisSliceROI
            
            for i = 1:app.z
                
                thisSliceROI = app.dyeRaw(round(app.topMean(i))-350:round(app.topMean(i))-100,:,i);
                mean_dye_signal(i) = mean2(thisSliceROI);
                
            end
            
            t_beforeUS = (app.tStep.Value.*(USOn-10:USOn-1))-30;
            I_beforeUS = mean_dye_signal(app.USOn.Value-10:app.USOn.Value-1);
            fit_beforeUS = coeffvalues(fit(t_beforeUS', I_beforeUS', 'poly1'));
            dIdt_before_US = fit_beforeUS(1);
            r_beforeUS = umPerPx*mean(app.diam(USOn-10:USOn-1))/2e4;
            slideROI_p_beforeUS = 1e6*(dIdt_before_US.*r_beforeUS)./(2*app.I0);
            
            t_US = (app.tStep.Value.*(USOn:USOff-1))-30;
            I_US = mean_dye_signal(USOn:USOff-1);
            fit_US = coeffvalues(fit(t_US', I_US', 'poly1'));
            dIdt_US = fit_US(1);
            r_US = umPerPx*mean(app.diam(USOn:USOff-1))/2e4;
            slideROI_p_US = 1e6*(dIdt_US.*r_US)./(2*app.I0);
            
            t_afterUS = (app.tStep.Value.*(USOff:USOff+9))-30;
            I_afterUS = mean_dye_signal(USOff:USOff+9);
            fit_afterUS = coeffvalues(fit(t_afterUS', I_afterUS', 'poly1'));
            dIdt_after_US = fit_afterUS(1);
            r_afterUS = umPerPx*mean(app.diam(USOff:USOff+9))/2e4;
            slideROI_p_afterUS = 1e6*(dIdt_after_US.*r_afterUS)./(2*app.I0);
            
            app.UITable.Data{3,1} = slideROI_p_beforeUS;
            app.UITable.Data{3,2} = slideROI_p_US;
            app.UITable.Data{3,3} = slideROI_p_afterUS;
            
            
        end

        % Value changed function: NumFramesLower
        function NumFramesLowerValueChanged(app, event)
            value = app.NumFramesLower.Value;
            
            app.I0 = mean([app.ROI(value:app.NumFramesUpper.Value).centMean]);
            app.I_0EditField.Value = app.I0;
            
            %WholeGel method
            
            mean_dye_signal = app.topBotMean;
            USOn = app.USOn.Value;
            USOff = app.USOff.Value;
            umPerPx = app.umpx.Value;
            
            t_beforeUS = (app.tStep.Value.*(USOn-10:USOn-1))-30;
            I_beforeUS = mean_dye_signal(app.USOn.Value-10:app.USOn.Value-1);
            fit_beforeUS = coeffvalues(fit(t_beforeUS', I_beforeUS', 'poly1'));
            dIdt_before_US = fit_beforeUS(1);
            r_beforeUS = umPerPx*mean(app.diam(USOn-10:USOn-1))./2e4;
            whole_gel_p_beforeUS = 1e6*(dIdt_before_US.*r_beforeUS)./(2*app.I0);
            
            t_US = (app.tStep.Value.*(USOn:USOff-1))-30;
            I_US = mean_dye_signal(USOn:USOff-1);
            fit_US = coeffvalues(fit(t_US', I_US', 'poly1'));
            dIdt_US = fit_US(1);
            r_US = umPerPx*mean(app.diam(USOn:USOff-1))/2e4;
            whole_gel_p_US = 1e6*(dIdt_US.*r_US)./(2*app.I0);
            
            t_afterUS = (app.tStep.Value.*(USOff:USOff+9))-30;
            I_afterUS = mean_dye_signal(USOff:USOff+9);
            fit_afterUS = coeffvalues(fit(t_afterUS', I_afterUS', 'poly1'));
            dIdt_after_US = fit_afterUS(1);
            r_afterUS = umPerPx*mean(app.diam(USOff:USOff+9))/2e4;
            whole_gel_p_afterUS = 1e6*(dIdt_after_US.*r_afterUS)./(2*app.I0);
            
            app.UITable.Data{1,1} = whole_gel_p_beforeUS;
            app.UITable.Data{1,2} = whole_gel_p_US;
            app.UITable.Data{1,3} = whole_gel_p_afterUS;
            drawnow
            
            %User ROI
            
            verts = app.verts;
            
            xmin = min(verts(:,1)); xmax = max(verts(:,1)); ymin = min(verts(:,1)); ymax = max(verts(:,2)); zmax = length(app.dyeRaw);
            
            ROI_eachSlice = subvolume(app.dyeRaw, [xmin xmax ymin ymax 1 zmax]);
            
            [xSlice, ySlice, zSlice] = size(ROI_eachSlice);
            
            for i = 1:zSlice
                
                mean_dye_signal(i) = mean2(ROI_eachSlice(:,:,i));
                temp = mean(ROI_eachSlice(:,:,i),2);
                profiles(:,i) = temp;
            end
            
            t_beforeUS = (app.tStep.Value.*(USOn-10:USOn-1))-30;
            I_beforeUS = mean_dye_signal(app.USOn.Value-10:app.USOn.Value-1);
            fit_beforeUS = coeffvalues(fit(t_beforeUS', I_beforeUS', 'poly1'));
            dIdt_before_US = fit_beforeUS(1);
            r_beforeUS = umPerPx*mean(app.diam(USOn-10:USOn-1))/2e4;
            userROI_p_beforeUS = 1e6*(dIdt_before_US.*r_beforeUS)./(2*app.I0);
            
            t_US = (app.tStep.Value.*(USOn:USOff-1))-30;
            I_US = mean_dye_signal(USOn:USOff-1);
            fit_US = coeffvalues(fit(t_US', I_US', 'poly1'));
            dIdt_US = fit_US(1);
            r_US = umPerPx*mean(app.diam(USOn:USOff-1))/2e4;
            userROI_p_US = 1e6*(dIdt_US.*r_US)./(2*app.I0);
            
            t_afterUS = (app.tStep.Value.*(USOff:USOff+9))-30;
            I_afterUS = mean_dye_signal(USOff:USOff+9);
            fit_afterUS = coeffvalues(fit(t_afterUS', I_afterUS', 'poly1'));
            dIdt_after_US = fit_afterUS(1);
            r_afterUS = umPerPx*mean(app.diam(USOff:USOff+9))/2e4;
            userROI_p_afterUS = 1e6*(dIdt_after_US.*r_afterUS)./(2*app.I0);
            
            app.UITable.Data{2,1} = userROI_p_beforeUS;
            app.UITable.Data{2,2} = userROI_p_US;
            app.UITable.Data{2,3} = userROI_p_afterUS;
            
            
            % Sliding ROI
            
            clear mean_dye_signal thisSliceROI
            
            for i = 1:app.z
                
                thisSliceROI = app.dyeRaw(round(app.topMean(i))-350:round(app.topMean(i))-100,:,i);
                mean_dye_signal(i) = mean2(thisSliceROI);
                
            end
            
            t_beforeUS = (app.tStep.Value.*(USOn-10:USOn-1))-30;
            I_beforeUS = mean_dye_signal(app.USOn.Value-10:app.USOn.Value-1);
            fit_beforeUS = coeffvalues(fit(t_beforeUS', I_beforeUS', 'poly1'));
            dIdt_before_US = fit_beforeUS(1);
            r_beforeUS = umPerPx*mean(app.diam(USOn-10:USOn-1))/2e4;
            slideROI_p_beforeUS = 1e6*(dIdt_before_US.*r_beforeUS)./(2*app.I0);
            
            t_US = (app.tStep.Value.*(USOn:USOff-1))-30;
            I_US = mean_dye_signal(USOn:USOff-1);
            fit_US = coeffvalues(fit(t_US', I_US', 'poly1'));
            dIdt_US = fit_US(1);
            r_US = umPerPx*mean(app.diam(USOn:USOff-1))/2e4;
            slideROI_p_US = 1e6*(dIdt_US.*r_US)./(2*app.I0);
            
            t_afterUS = (app.tStep.Value.*(USOff:USOff+9))-30;
            I_afterUS = mean_dye_signal(USOff:USOff+9);
            fit_afterUS = coeffvalues(fit(t_afterUS', I_afterUS', 'poly1'));
            dIdt_after_US = fit_afterUS(1);
            r_afterUS = umPerPx*mean(app.diam(USOff:USOff+9))/2e4;
            slideROI_p_afterUS = 1e6*(dIdt_after_US.*r_afterUS)./(2*app.I0);
            
            app.UITable.Data{3,1} = slideROI_p_beforeUS;
            app.UITable.Data{3,2} = slideROI_p_US;
            app.UITable.Data{3,3} = slideROI_p_afterUS;
            
            
        end

        % Value changed function: NumFramesUpper
        function NumFramesUpperValueChanged(app, event)
            value = app.NumFramesUpper.Value;
            
            app.I0 = mean([app.ROI(app.NumFramesLower.Value:value).centMean]);
            app.I_0EditField.Value = app.I0;
            
            %WholeGel method
            
            mean_dye_signal = app.topBotMean;
            USOn = app.USOn.Value;
            USOff = app.USOff.Value;
            umPerPx = app.umpx.Value;
            
            t_beforeUS = (app.tStep.Value.*(USOn-10:USOn-1))-30;
            I_beforeUS = mean_dye_signal(app.USOn.Value-10:app.USOn.Value-1);
            fit_beforeUS = coeffvalues(fit(t_beforeUS', I_beforeUS', 'poly1'));
            dIdt_before_US = fit_beforeUS(1);
            r_beforeUS = umPerPx*mean(app.diam(USOn-10:USOn-1))./2e4;
            whole_gel_p_beforeUS = 1e6*(dIdt_before_US.*r_beforeUS)./(2*app.I0);
            
            t_US = (app.tStep.Value.*(USOn:USOff-1))-30;
            I_US = mean_dye_signal(USOn:USOff-1);
            fit_US = coeffvalues(fit(t_US', I_US', 'poly1'));
            dIdt_US = fit_US(1);
            r_US = umPerPx*mean(app.diam(USOn:USOff-1))/2e4;
            whole_gel_p_US = 1e6*(dIdt_US.*r_US)./(2*app.I0);
            
            t_afterUS = (app.tStep.Value.*(USOff:USOff+9))-30;
            I_afterUS = mean_dye_signal(USOff:USOff+9);
            fit_afterUS = coeffvalues(fit(t_afterUS', I_afterUS', 'poly1'));
            dIdt_after_US = fit_afterUS(1);
            r_afterUS = umPerPx*mean(app.diam(USOff:USOff+9))/2e4;
            whole_gel_p_afterUS = 1e6*(dIdt_after_US.*r_afterUS)./(2*app.I0);
            
            app.UITable.Data{1,1} = whole_gel_p_beforeUS;
            app.UITable.Data{1,2} = whole_gel_p_US;
            app.UITable.Data{1,3} = whole_gel_p_afterUS;
            drawnow
            
            %User ROI
            
            verts = app.verts;
            
            xmin = min(verts(:,1)); xmax = max(verts(:,1)); ymin = min(verts(:,1)); ymax = max(verts(:,2)); zmax = length(app.dyeRaw);
            
            ROI_eachSlice = subvolume(app.dyeRaw, [xmin xmax ymin ymax 1 zmax]);
            
            [xSlice, ySlice, zSlice] = size(ROI_eachSlice);
            
            for i = 1:zSlice
                
                mean_dye_signal(i) = mean2(ROI_eachSlice(:,:,i));
                temp = mean(ROI_eachSlice(:,:,i),2);
                profiles(:,i) = temp;
            end
            
            t_beforeUS = (app.tStep.Value.*(USOn-10:USOn-1))-30;
            I_beforeUS = mean_dye_signal(app.USOn.Value-10:app.USOn.Value-1);
            fit_beforeUS = coeffvalues(fit(t_beforeUS', I_beforeUS', 'poly1'));
            dIdt_before_US = fit_beforeUS(1);
            r_beforeUS = umPerPx*mean(app.diam(USOn-10:USOn-1))/2e4;
            userROI_p_beforeUS = 1e6*(dIdt_before_US.*r_beforeUS)./(2*app.I0);
            
            t_US = (app.tStep.Value.*(USOn:USOff-1))-30;
            I_US = mean_dye_signal(USOn:USOff-1);
            fit_US = coeffvalues(fit(t_US', I_US', 'poly1'));
            dIdt_US = fit_US(1);
            r_US = umPerPx*mean(app.diam(USOn:USOff-1))/2e4;
            userROI_p_US = 1e6*(dIdt_US.*r_US)./(2*app.I0);
            
            t_afterUS = (app.tStep.Value.*(USOff:USOff+9))-30;
            I_afterUS = mean_dye_signal(USOff:USOff+9);
            fit_afterUS = coeffvalues(fit(t_afterUS', I_afterUS', 'poly1'));
            dIdt_after_US = fit_afterUS(1);
            r_afterUS = umPerPx*mean(app.diam(USOff:USOff+9))/2e4;
            userROI_p_afterUS = 1e6*(dIdt_after_US.*r_afterUS)./(2*app.I0);
            
            app.UITable.Data{2,1} = userROI_p_beforeUS;
            app.UITable.Data{2,2} = userROI_p_US;
            app.UITable.Data{2,3} = userROI_p_afterUS;
            
            
            % Sliding ROI
            
            clear mean_dye_signal thisSliceROI
            
            for i = 1:app.z
                
                thisSliceROI = app.dyeRaw(round(app.topMean(i))-350:round(app.topMean(i))-100,:,i);
                mean_dye_signal(i) = mean2(thisSliceROI);
                
            end
            
            t_beforeUS = (app.tStep.Value.*(USOn-10:USOn-1))-30;
            I_beforeUS = mean_dye_signal(app.USOn.Value-10:app.USOn.Value-1);
            fit_beforeUS = coeffvalues(fit(t_beforeUS', I_beforeUS', 'poly1'));
            dIdt_before_US = fit_beforeUS(1);
            r_beforeUS = umPerPx*mean(app.diam(USOn-10:USOn-1))/2e4;
            slideROI_p_beforeUS = 1e6*(dIdt_before_US.*r_beforeUS)./(2*app.I0);
            
            t_US = (app.tStep.Value.*(USOn:USOff-1))-30;
            I_US = mean_dye_signal(USOn:USOff-1);
            fit_US = coeffvalues(fit(t_US', I_US', 'poly1'));
            dIdt_US = fit_US(1);
            r_US = umPerPx*mean(app.diam(USOn:USOff-1))/2e4;
            slideROI_p_US = 1e6*(dIdt_US.*r_US)./(2*app.I0);
            
            t_afterUS = (app.tStep.Value.*(USOff:USOff+9))-30;
            I_afterUS = mean_dye_signal(USOff:USOff+9);
            fit_afterUS = coeffvalues(fit(t_afterUS', I_afterUS', 'poly1'));
            dIdt_after_US = fit_afterUS(1);
            r_afterUS = umPerPx*mean(app.diam(USOff:USOff+9))/2e4;
            slideROI_p_afterUS = 1e6*(dIdt_after_US.*r_afterUS)./(2*app.I0);
            
            app.UITable.Data{3,1} = slideROI_p_beforeUS;
            app.UITable.Data{3,2} = slideROI_p_US;
            app.UITable.Data{3,3} = slideROI_p_afterUS;
        end

        % Button pushed function: SelectoutputdirectoryButton
        function SelectoutputdirectoryButtonPushed(app, event)
            app.outDir = uipickfiles('REFilter','^','out','struct','Prompt','Select output directory');
            
            app.outdirLamp.Color = [0 1 0];
        end

        % Button pushed function: ExportplotsanddataButton
        function ExportplotsanddataButtonPushed(app, event)
            app.ExportLamp.Color = [1 1 0];
            outDir = app.outDir;
            
            app.endoShow = app.endoEdges(:,:,1:10:end);
            edgeVis = figure('Visible', 'off');
            montage(app.endoShow);
            saveas(edgeVis, strcat([outDir.name], "\edgeSummary.png"))
            
            diamplot = figure('Visible', 'off');
            plot(gca,(app.tStep.Value*[1:app.z])-30, app.diamMean.*app.umpx.Value, 'LineWidth', 2)
            xlabel(gca,'Time (sec)')
            ylabel(gca,'Diameter (\mum)')
            set(gca,'FontSize',16)
            saveas(diamplot, strcat([outDir.name], "\diamPlot.png"))
            
            plotAccum = figure('Visible', 'off');
            plot(gca, app.tStep.Value*[1:app.z]-30, app.topBotMean*100, 'LineWidth', 2);
            xlabel(gca, 'Time (sec)')
            ylabel(gca, 'Fluorescence intensity (%)')
            set(gca,'FontSize',16)
            saveas(plotAccum, strcat([outDir.name], "\AccumDye.png"))
            
            stackProfs = figure('Visible', 'off');
            hold all
            for k = 1:length(app.ROI)
                plot(gca,100*[app.ROI(k).topProfileMean], 'Color', [1-(k/length(app.ROI)), 0, k/length(app.ROI)])
            end
            
            xlabel(gca,'Distance from channel edge (px)')
            ylabel(gca,'Fluorescence Intensity (%)')
            set(gca,'FontSize',16)
            
            % Create arrow
            annotation(gcf,'arrow',[0.13, 0.632539682539682],...
                [0.16, 0.734656084656085],'LineWidth',3);
            
            % Create textbox
            annotation(gcf,'textbox',...
                [0.624015873015873 0.743121693121694 0.0585238095238095 0.0582010582010581],...
                'String',{'Time'},...
                'FontSize',14,...
                'FitBoxToText','off',...
                'EdgeColor',[1 1 1]);
                   
            saveas(stackProfs, strcat([outDir.name], "\AllProfiles.png"))
            
            %Make and plot heatmap
            maxL = 0;
            for g = 1:length(app.ROI)
                if length([app.ROI(g).topProfileMean]) > maxL
                    maxL = length([app.ROI(g).topProfileMean]);
                end
            end
            
            HeatMap = zeros(maxL,length(app.ROI));
            
            for j = 1:length(app.ROI)
                
                HeatMap(1:length([app.ROI(j).topProfileMean]),j) = [app.ROI(j).topProfileMean];
                
            end
            
            heatmap = figure('Visible', 'off');
            imagesc(gca,HeatMap)
            xlabel(gca,'Time -->')
            ylabel(gca,'<-- Diffusion profile')
            set(gca, 'YDir','reverse')
            saveas(heatmap, strcat([outDir.name], "\Heatmap.png"))
            
            
            profiles3D = figure('Visible','off');
            h = surf(gca, HeatMap);
            set(h,'LineStyle','none')
            xlabel(gca,'Time -->')
            ylabel(gca,'<-- Diffusion Profile')
            zlabel(gca,'Signal')
            saveas(profiles3D, strcat([outDir.name], "\3DPlot.png"))
            
            permData = get(app.UITable, 'Data');
            csvwrite(strcat([outDir.name], "\permData.csv"),permData)
            
            app.ExportLamp.Color = [0 1 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectHUVECimagesButton = uibutton(app.UIFigure, 'push');
            app.SelectHUVECimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectHUVECimagesButtonPushed, true);
            app.SelectHUVECimagesButton.Text = 'Select HUVEC images';
            app.SelectdyeimagesButton = uibutton(app.UIFigure, 'push');
            app.SelectdyeimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdyeimagesButtonPushed, true);
            app.SelectdyeimagesButton.Text = 'Select dye images ';
            app.ImportDyeLamp = uilamp(app.UIFigure);
            app.ImportimagesButton = uibutton(app.UIFigure, 'push');
            app.ImportimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportimagesButtonPushed, true);
            app.ImportimagesButton.Text = 'Import images';
            app.DetectedgesButton = uibutton(app.UIFigure, 'push');
            app.DetectedgesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectedgesButtonPushed, true);
            app.DetectedgesButton.Text = 'Detect edges';
            app.edgeDetectLamp = uilamp(app.UIFigure);
            app.ExtractdyeprofilesButton = uibutton(app.UIFigure, 'push');
            app.ExtractdyeprofilesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractdyeprofilesButtonPushed, true);
            app.ExtractdyeprofilesButton.Text = 'Extract dye profiles';
            app.ExtractProfLamp = uilamp(app.UIFigure);
            app.ComputepermeabilityButton = uibutton(app.UIFigure, 'push');
            app.ComputepermeabilityButton.ButtonPushedFcn = createCallbackFcn(app, @ComputepermeabilityButtonPushed, true);
            app.ComputepermeabilityButton.Text = 'Compute permeability';
            app.PermeabilityLamp = uilamp(app.UIFigure);
            app.SelectoutputdirectoryButton = uibutton(app.UIFigure, 'push');
            app.SelectoutputdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputdirectoryButtonPushed, true);
            app.SelectoutputdirectoryButton.Text = 'Select output directory';
            app.ExportplotsanddataButton = uibutton(app.UIFigure, 'push');
            app.ExportplotsanddataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportplotsanddataButtonPushed, true);
            app.ExportplotsanddataButton.Text = 'Export plots and data';
            app.ExportLamp = uilamp(app.UIFigure);
            app.ExperimentalParametersLabel = uilabel(app.UIFigure);
            app.ExperimentalParametersLabel.Text = 'Experimental Parameters';
            app.TimebetweenframessEditFieldLabel = uilabel(app.UIFigure);
            app.TimebetweenframessEditFieldLabel.Text = 'Time between frames (s)';
            app.tStep = uieditfield(app.UIFigure, 'numeric');
            app.tStep.Value = 30;
            app.ImagescaleumpxEditFieldLabel = uilabel(app.UIFigure);
            app.ImagescaleumpxEditFieldLabel.Text = 'Image scale (um/px)';
            app.umpx = uieditfield(app.UIFigure, 'numeric');
            app.umpx.Value = 0.64;
            app.UltrasoundonframeEditFieldLabel = uilabel(app.UIFigure);
            app.UltrasoundonframeEditFieldLabel.Text = 'Ultrasound on frame';
            app.USOn = uieditfield(app.UIFigure, 'numeric');
            app.USOn.Value = 21;
            app.UltrasoundoffframeEditFieldLabel = uilabel(app.UIFigure);
            app.UltrasoundoffframeEditFieldLabel.Text = 'Ultrasound off frame';
            app.USOff = uieditfield(app.UIFigure, 'numeric');
            app.USOff.Value = 31;
            app.MillerLabIanKinstlingerrev0420Label = uilabel(app.UIFigure);
            app.MillerLabIanKinstlingerrev0420Label.Text = 'MillerLab / Ian Kinstlinger; rev. 04/20';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.HUVECedgeButton = uiradiobutton(app.ButtonGroup);
            app.HUVECedgeButton.Text = 'HUVEC + edge';
            app.HUVECedgeButton.Value = true;
            app.DiamovertimeButton = uiradiobutton(app.ButtonGroup);
            app.DiamovertimeButton.Text = 'Diam over time';
            app.DyeprofileButton = uiradiobutton(app.ButtonGroup);
            app.DyeprofileButton.Text = 'Dye profile';
            app.DyeedgeButton = uiradiobutton(app.ButtonGroup);
            app.DyeedgeButton.Text = 'Dye + edge';
            app.DyeAccum = uiradiobutton(app.ButtonGroup);
            app.DyeAccum.Text = 'Cumulative dye over time';
            app.ImageNumberSliderLabel = uilabel(app.UIFigure);
            app.ImageNumberSliderLabel.Text = 'Image Number';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging2, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Before US'; 'During US'; 'After US'};
            app.UITable.RowName = {'Whole gel'; 'User ROI'; 'Sliding ROI'};
            app.PermeabilitydataLabel = uilabel(app.UIFigure);
            app.PermeabilitydataLabel.Text = {'Permeability data'; ''};
            app.I_0EditFieldLabel = uilabel(app.UIFigure);
            app.I_0EditFieldLabel.Text = 'I_0';
            app.I_0EditField = uieditfield(app.UIFigure, 'numeric');
            app.ROIRed = uilabel(app.UIFigure);
            app.ROIRed.Text = '^ Draw ROI box and double-click ^';
            app.ImportProgress = uilabel(app.UIFigure);
            app.ImportProgress.Text = '100 %';
            app.EndoLamp = uilamp(app.UIFigure);
            app.CalulateI_0usingframesLabel = uilabel(app.UIFigure);
            app.CalulateI_0usingframesLabel.Text = {'Calulate I_0 '; 'using frames:'};
            app.NumFramesLower = uispinner(app.UIFigure);
            app.NumFramesLower.ValueChangedFcn = createCallbackFcn(app, @NumFramesLowerValueChanged, true);
            app.NumFramesLower.Value = 2;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            app.NumFramesUpper = uispinner(app.UIFigure);
            app.NumFramesUpper.ValueChangedFcn = createCallbackFcn(app, @NumFramesUpperValueChanged, true);
            app.NumFramesUpper.Value = 22;
            app.outdirLamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = v4DiffusionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end